classdef Multifunctional_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        RGBButton    matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Label_6      matlab.ui.control.Label
        DropDown_2   matlab.ui.control.DropDown
        Label_5      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Slider4      matlab.ui.control.Slider
        Slider3      matlab.ui.control.Slider
        Slider2      matlab.ui.control.Slider
        Slider       matlab.ui.control.Slider
        Image2       matlab.ui.control.Image
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        Image        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'},'选择图像');
            if isequal(file,0)
                disp('未选择任何图像');
            else
                fullpath = fullfile(path, file);
                app.Image2.ImageSource = fullpath;
                app.Image.ImageSource = fullpath;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '无滤镜'
                    % 关闭所有Slider和Label
                    app.Slider.Visible = "off";
                    app.Slider2.Visible = "off";
                    app.Slider3.Visible = "off";
                    app.Slider4.Visible = "off";
                    app.Label_2.Visible = "off";
                    app.Label_3.Visible = "off";
                    app.Label_4.Visible = "off";
                    app.Label_5.Visible = "off";
                    app.ButtonGroup.Visible = "off";
                    % app.Label_6.Visible = "off";
                    % app.DropDown_2.Visible = "off";
                case '老照片'
                    % 本滤镜需要用到的Slider和Label开启
                    % Label
                    app.Label_2.Visible = "on";
                    app.Label_2.Text = '噪点';
                    % Slider
                    app.Slider.Visible = "on";
                    app.Slider.Limits = [0,0.5];
                    app.Slider.Value = 0.04;
                    % 本滤镜不需要用到的Slider和Label关闭
                    % app.Slider.Visible = "off";
                    app.Slider2.Visible = "off";
                    app.Slider3.Visible = "off";
                    app.Slider4.Visible = "off";
                    % app.Label_2.Visible = "off";
                    app.Label_3.Visible = "off";
                    app.Label_4.Visible = "off";
                    app.Label_5.Visible = "off";
                    % app.Label_6.Visible = "off";
                    % app.DropDown_2.Visible = "off"; 
                    app.ButtonGroup.Visible = "off";
                case '动态模糊'
                    % 本滤镜需要用到的Slider和Label开启
                    % Label
                    app.Label_2.Visible = "on";
                    app.Label_2.Text = '程度';
                    app.Label_3.Visible = "on";
                    app.Label_3.Text = '方向';
                    % app.Label_6.Visible = "on";
                    % Slider
                    app.Slider.Visible = "on";
                    app.Slider.Limits = [0,100];
                    app.Slider.Value = 20;
                    app.Slider2.Visible = "on";
                    app.Slider2.Limits = [0,360];
                    app.Slider2.Value = 45;
                    % app.DropDown_2.Visible = "on";
                    % 本滤镜不需要用到的Slider和Label关闭
                    % app.Slider.Visible = "off";
                    % app.Slider2.Visible = "off";
                    app.Slider3.Visible = "off";
                    app.Slider4.Visible = "off";
                    % app.Label_2.Visible = "off";
                    % app.Label_3.Visible = "off";
                    app.Label_4.Visible = "off";
                    app.Label_5.Visible = "off";
                    app.ButtonGroup.Visible = "off";
                case '二值'
                    % 本滤镜需要用到的组件开启
                    % Label
                    app.Label_2.Visible = "on";
                    app.Label_2.Text = '阈值';
                    app.Slider.Visible = "on";
                    app.Slider.Limits = [0,255];
                    app.Slider.Value = 0;
                    app.Slider2.Limits = [0,255];
                    app.Slider2.Value = 0;
                    app.Slider3.Limits = [0,255];
                    app.Slider3.Value = 0;
                    app.ButtonGroup.Visible = "on";
                    % 本滤镜不需要用到的组件关闭
                    % app.Slider.Visible = "off";
                    app.Slider2.Visible = "off";
                    app.Slider3.Visible = "off";
                    app.Slider4.Visible = "off";
                    % app.Label_2.Visible = "off";
                    app.Label_3.Visible = "off";
                    app.Label_4.Visible = "off";
                    app.Label_5.Visible = "off";
                   
                case '手动调节'
                    % 本滤镜需要用到的Slider和Label开启
                    % Label
                    app.Label_2.Visible = "on";
                    app.Label_2.Text = '亮度';
                    app.Label_3.Visible = "on";
                    app.Label_3.Text = '对比度';
                    app.Label_4.Visible = "on";
                    app.Label_4.Text = '饱和度';
                    app.Label_5.Visible = "on";
                    app.Label_5.Text = '锐度';
                    % Slider
                    app.Slider.Visible = "on";
                    app.Slider.Limits = [-128,128];
                    app.Slider.Value = 0;
                    app.Slider2.Visible = "on";
                    app.Slider2.Limits = [0,5];
                    app.Slider2.Value = 1;
                    app.Slider3.Visible = "on";
                    app.Slider3.Limits = [0,2];
                    app.Slider3.Value = 1;
                    app.Slider4.Visible = "on";
                    app.Slider4.Limits = [0,1];
                    app.Slider4.Value = 0;
                    % 本滤镜不需要用到的Slider和Label关闭
                    app.ButtonGroup.Visible = "off";
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.DropDown.Value;
            image = app.Image2.ImageSource;
            switch value
                case '无滤镜'
                    app.Image.ImageSource = app.Image2.ImageSource;
                case '老照片'
                        
                     % 读取原始彩色图像
                     originalImage = imread(image);
    
                     % 转换为灰度图像
                     grayImage = rgb2gray(originalImage);
    
                     % 添加噪点
                     % 与Slider进行结合
                    % noisyImage = imnoise(grayImage,'speckle',0.04);
                    noisyImage = imnoise(grayImage,'speckle',app.Slider.Value);
    
                    % 调整对比度
                    contrastAdjusted = imadjust(noisyImage);
    
                    % 创建复古色调映射
                    sepiaTone = [.393 .769 .189; .349 .686 .168; .272 .534 .131];
    
                    % 应用复古色调映射
                    sepiaImage = uint8(zeros(size(originalImage,1), size(originalImage,2), 3));
                    for row = 1:size(originalImage,1)
                      for col = 1:size(originalImage,2)
                           grayscaleValue = double(contrastAdjusted(row, col));
                          sepiaImage(row, col, :) = uint8(sepiaTone * [grayscaleValue; grayscaleValue; grayscaleValue]);
                      end
                    end
    
                    % 显示结果
                    app.Image.ImageSource = sepiaImage;
                case '动态模糊'
                    % 读取图像
                    img = imread(image);

                    % 创建动态模糊的核（模糊程度和方向）
                    % h = fspecial('motion', 20, 45);
                    if app.Slider.Value == 0
                        app.Slider.Value = 0.1;
                    end
                    
                    
                    h = fspecial(app.DropDown_2.Value, app.Slider.Value, app.Slider2.Value);

                    % 应用动态模糊核
                    blurredImg = imfilter(img, h, 'replicate');
                    % 显示图像
                    app.Image.ImageSource = blurredImg;
                case '二值'
                    % 读取图像
                    img = imread(image);
                    if app.ButtonGroup.SelectedObject == app.Button_3
                        img = rgb2gray(img);
                        limit = app.Slider.Value;
                        % 生成一个与输入图像大小相同的矩阵，用于保存处理后的图像
                        thresholded_image = zeros(size(img),'uint8');

                        % 根据阈值对图像进行处理
                        thresholded_image(img > limit) = 255;
                        thresholded_image(img <= limit) = 0;

                        thresholded_image = cat(3,thresholded_image,thresholded_image,thresholded_image);
                        app.Image.ImageSource = thresholded_image;
                    elseif app.ButtonGroup.SelectedObject == app.RGBButton
                        limitR = app.Slider.Value;
                        limitG = app.Slider2.Value;
                        limitB = app.Slider3.Value; % 三个阈值
                        % 复制输入图像作为阈值处理后的输出图像
                        thresholded_image = img;

                        % 对每个颜色通道应用不同的阈值
                        thresholded_image(:,:,1) = img(:,:,1) > limitR;  % R 通道阈值处理
                        thresholded_image(:,:,2) = img(:,:,2) > limitG;  % G 通道阈值处理
                        thresholded_image(:,:,3) = img(:,:,3) > limitB;  % B 通道阈值处理

                        % 将结果转换为uint8类型
                        thresholded_image = uint8(thresholded_image * 255);

                        app.Image.ImageSource = thresholded_image;
                    end
                case '手动调节'
                    % 读取图像
                    img = imread(image);
                    % 亮度调节
                    brightness = app.Slider.Value;
                    img1 = img + brightness;
                    img1 = max(img1,0);
                    img1 = min(img1,255); % 限制结果在0和255之间（uint8范围）
                    img1 = uint8(img1)  % 将结果转换为uint8类型
                    % 对比度调节
                    contrastRatio = app.Slider2.Value;
                    img2 = imadjust(img1,[],[],contrastRatio);
                    img2 = max(img2,0);
                    img2 = min(img2,255);
                    img2 = uint8(img2);
                    % 饱和度调节
                    saturability = app.Slider3.Value;
                    img3_hsv = rgb2hsv(img2);
                    img3_hsv(:,:,2) = img3_hsv(:,:,2)*saturability;
                    img3 = hsv2rgb(img3_hsv);
                    img3 = max(img3,0);
                    img3 = min(img3,255);
                    img3 = im2uint8(img3);
                    % 锐度调节
                    acutance = app.Slider4.Value;
                    sharpFilter = [0 -1 0; -1 5 -1; 0 -1 0];    % 定义拉普拉斯滤波器
                    sharpImage = imfilter(img3,sharpFilter,'replicate');    % 应用滤波器来增强图像的边缘
                    img4_sha = img3 + acutance * sharpImage;    % 结合原始图像和增强后的边缘部分
                    img4_sha = max(img4_sha,0);
                    img4_sha = min(img4_sha,255);   % 将结果限制在0和255之间（uint8范围）
                    img4 = uint8(img4_sha);
                    % 显示图片
                    app.Image.ImageSource = img4;
            end
        end

        % Callback function
        function Button_3ValueChanged(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
        selectedButton = app.ButtonGroup.SelectedObject; % 获取当前选中的按钮

        if selectedButton == app.RGBButton  % 如果选中了RGBButton
            app.Slider.Visible = 'on';
            app.Slider2.Visible = 'on';
            app.Slider3.Visible = 'on';
            app.Label_3.Visible = "on";
            app.Label_4.Visible = "on";
            app.Label_2.Text = '红色';
            app.Label_3.Text = '绿色';
            app.Label_4.Text = '蓝色';
        elseif selectedButton == app.Button_3  % 如果选中了Button_3
            app.Slider.Visible = 'on';
            app.Slider2.Visible = 'off';
            app.Slider3.Visible = 'off';
            app.Label_2.Text = '阈值';
            app.Label_3.Visible = 'off';
            app.Label_4.Visible = 'off';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择滤镜';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'无滤镜', '老照片', '动态模糊', '二值', '手动调节'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '无滤镜';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '生成图像';
            app.Image2 = uiimage(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Visible = 'off';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Visible = 'off';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Visible = 'off';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'average', 'disk', 'gaussian', 'laplacian', 'log', 'motion', 'prewitt', 'sobel'};
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'motion';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '滤波器';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '黑白';
            app.Button_3.Value = true;
            app.RGBButton = uiradiobutton(app.ButtonGroup);
            app.RGBButton.Text = 'RGB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Multifunctional_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end